classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Tab_Input                     matlab.ui.container.Tab
        BrowseButton                  matlab.ui.control.Button
        ChooseplatemapLabel           matlab.ui.control.Label
        ChooseplatemapEditField       matlab.ui.control.EditField
        Tab_Spot                      matlab.ui.container.Tab
        AlgorithmDropDownLabel        matlab.ui.control.Label
        AlgorithmDropDown             matlab.ui.control.DropDown
        InputChannelEditFieldLabel    matlab.ui.control.Label
        InputChannelEditField         matlab.ui.control.EditField
        OutputSeedNameEditFieldLabel  matlab.ui.control.Label
        OutputSeedNameEditField       matlab.ui.control.EditField
        SaveCurrentFigureButton       matlab.ui.control.Button
        Tab_Segment                   matlab.ui.container.Tab
        Tab_Measure                   matlab.ui.container.Tab
        Tab_Track                     matlab.ui.container.Tab
        Tab_Plot                      matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        ImageSelectionLabel           matlab.ui.control.Label
        ChannelsLabel                 matlab.ui.control.Label
        CheckBox_9                    matlab.ui.control.CheckBox
        LabelOverlay                  matlab.ui.control.Label
        MetricOverlayLabel            matlab.ui.control.Label
        Slider_9                      matlab.ui.control.Slider
        Button_3                      matlab.ui.control.Button
        Button_5                      matlab.ui.control.Button
        ROSLabel                      matlab.ui.control.Label
        CheckBox_10                   matlab.ui.control.CheckBox
        Slider_11                     matlab.ui.control.Slider
        Button_6                      matlab.ui.control.Button
        HA2XLabel                     matlab.ui.control.Label
        CheckBox_11                   matlab.ui.control.CheckBox
        Slider_12                     matlab.ui.control.Slider
        Button_7                      matlab.ui.control.Button
        DAPILabel_3                   matlab.ui.control.Label
        CheckBox_12                   matlab.ui.control.CheckBox
        Slider_13                     matlab.ui.control.Slider
        Button_8                      matlab.ui.control.Button
        SELabel                       matlab.ui.control.Label
        Button_16                     matlab.ui.control.Button
        Button_17                     matlab.ui.control.Button
        RowDropDownLabel              matlab.ui.control.Label
        RowDropDown                   matlab.ui.control.DropDown
        ColumnDropDownLabel           matlab.ui.control.Label
        ColumnDropDown                matlab.ui.control.DropDown
        FieldDropDownLabel            matlab.ui.control.Label
        FieldDropDown                 matlab.ui.control.DropDown
        TimepointDropDownLabel        matlab.ui.control.Label
        TimepointDropDown             matlab.ui.control.DropDown
        Button_18                     matlab.ui.control.Button
        CheckBox_13                   matlab.ui.control.CheckBox
        Slider_14                     matlab.ui.control.Slider
        Button_19                     matlab.ui.control.Button
        CellLabel                     matlab.ui.control.Label
        CheckBox_15                   matlab.ui.control.CheckBox
        Slider_16                     matlab.ui.control.Slider
        Button_21                     matlab.ui.control.Button
        NucSeedLabel                  matlab.ui.control.Label
        CheckBox_16                   matlab.ui.control.CheckBox
        Slider_17                     matlab.ui.control.Slider
        Button_22                     matlab.ui.control.Button
        NucLabel                      matlab.ui.control.Label
        ListBox                       matlab.ui.control.ListBox
        ExperimentDropDownLabel       matlab.ui.control.Label
        ExperimentDropDown            matlab.ui.control.DropDown
        PlateDropDownLabel            matlab.ui.control.Label
        PlateDropDown                 matlab.ui.control.DropDown
        Tab2                          matlab.ui.container.Tab
        SegmentationLabel             matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        MadewithinTorontoLabel        matlab.ui.control.Label
        DanielSniderLabel             matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        Button_Segment                matlab.ui.control.Button
        MeasurementsLabel             matlab.ui.control.Label
        TrackingLabel                 matlab.ui.control.Label
        PlottingLabel                 matlab.ui.control.Label
        SpottingLabel                 matlab.ui.control.Label
        InputDataLabel                matlab.ui.control.Label
        Button_Input                  matlab.ui.control.Button
        Button_11                     matlab.ui.control.Button
        Button_Spot                   matlab.ui.control.Button
        Button_Plot                   matlab.ui.control.Button
        Button_Track                  matlab.ui.control.Button
        Button_Measure                matlab.ui.control.Button
        Label_16                      matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        ColorButton                   matlab.ui.control.Button
        Seed1NucleusPanel             matlab.ui.container.Panel
        CheckBox_4                    matlab.ui.control.CheckBox
        ColorLamp_4                   matlab.ui.control.Lamp
        Slider_4                      matlab.ui.control.Slider
        SingleCellAnalysisWorkflowLabel  matlab.ui.control.Label
        DisplaySettingsLabel          matlab.ui.control.Label
        KafriLabLabel                 matlab.ui.control.Label
    end


    properties (Access = public)
        img % currently displayed image
        spotting
    end

    methods (Access = public)
    
         function init_spotting(app)
            app.AlgorithmDropDown.Items = {'spotA', 'spotB'};
            app.AlgorithmDropDown.Value = 'spotA';
            app.AlgorithmDropDown.ValueChangedFcn(app, 'Update') % trigger once
            app.spotting.fields{1}.ValueChangedFcn(app, 'Update') % trigger once
         end
         
         function draw_global_figure_(app)
             draw_global_figure(app)
         end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, eu)
            set(0,'DefaultFigureWindowStyle','docked')
            addpath(genpath('functions'));
            addpath(genpath('plugins'));
            % app.img = imread('images/example_cells/r02c02f01p01-ch1sk1fk1fl1.tiff');
            app.img = [];
            
            app.spotting = {};
            
            app.TabGroup.SelectedTab = app.Tab_Input;
            
            
            % init_spotting(app);
        
         
        end

        % Callback function
        function UITableCellEdit(app, event)
            update_plot(app);
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            algo_name = app.AlgorithmDropDown.Value;
            eval(['definition_' algo_name]);
            draw(algo_name, params, app, @createCallbackFcn) % initialize dynamic GUI
            app.spotting.fields{1}.ValueChangedFcn(app, 'Update') % trigger once
        end

        % Callback function
        function ButtonPushed(app, event)
            c = uisetcolor([1 1 0],'Select a color')
        end

        % Button pushed function: Button_Segment
        function Button_SegmentPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Segment;
        end

        % Button pushed function: Button_Input
        function Button_InputPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Input;
        end

        % Button pushed function: Button_Spot
        function Button_SpotPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Spot;
        end

        % Button pushed function: Button_Measure
        function Button_MeasurePushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Measure;
        end

        % Button pushed function: Button_Track
        function Button_TrackPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Track;
        end

        % Button pushed function: Button_Plot
        function Button_PlotPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Plot;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [FileName,PathName,FilterIndex] = uigetfile();
            app.ChooseplatemapEditField.Value = [PathName FileName];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Input = uitab(app.TabGroup);
            app.Tab_Input.Title = '                          ';
            app.BrowseButton = uibutton(app.Tab_Input, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ChooseplatemapLabel = uilabel(app.Tab_Input);
            app.ChooseplatemapLabel.Text = 'Choose plate map:';
            app.ChooseplatemapEditField = uieditfield(app.Tab_Input, 'text');
            app.ChooseplatemapEditField.Editable = 'off';
            app.Tab_Spot = uitab(app.TabGroup);
            app.Tab_Spot.Title = '                          ';
            app.AlgorithmDropDownLabel = uilabel(app.Tab_Spot);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.Tab_Spot);
            app.AlgorithmDropDown.Items = {};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = {};
            app.InputChannelEditFieldLabel = uilabel(app.Tab_Spot);
            app.InputChannelEditFieldLabel.Text = 'Input Channel';
            app.InputChannelEditField = uieditfield(app.Tab_Spot, 'text');
            app.InputChannelEditField.Value = 'Nucleus';
            app.OutputSeedNameEditFieldLabel = uilabel(app.Tab_Spot);
            app.OutputSeedNameEditFieldLabel.Text = 'Output Seed Name';
            app.OutputSeedNameEditField = uieditfield(app.Tab_Spot, 'text');
            app.OutputSeedNameEditField.Value = 'Nucleus';
            app.SaveCurrentFigureButton = uibutton(app.Tab_Spot, 'push');
            app.SaveCurrentFigureButton.Text = 'Save Current Figure';
            app.Tab_Segment = uitab(app.TabGroup);
            app.Tab_Segment.Title = '                          ';
            app.Tab_Measure = uitab(app.TabGroup);
            app.Tab_Measure.Title = '                          ';
            app.Tab_Track = uitab(app.TabGroup);
            app.Tab_Track.Title = '                          ';
            app.Tab_Plot = uitab(app.TabGroup);
            app.Tab_Plot.Title = '                          ';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.ImageSelectionLabel = uilabel(app.Tab);
            app.ImageSelectionLabel.Text = 'Image Selection';
            app.ChannelsLabel = uilabel(app.Tab);
            app.ChannelsLabel.Text = 'Channels';
            app.CheckBox_9 = uicheckbox(app.Tab);
            app.CheckBox_9.Text = '';
            app.LabelOverlay = uilabel(app.Tab);
            app.LabelOverlay.Text = 'Label Overlay';
            app.MetricOverlayLabel = uilabel(app.Tab);
            app.MetricOverlayLabel.Text = 'Metric Overlay';
            app.Slider_9 = uislider(app.Tab);
            app.Slider_9.MajorTicks = [];
            app.Slider_9.MajorTickLabels = {};
            app.Slider_9.MinorTicks = [];
            app.Button_3 = uibutton(app.Tab, 'push');
            app.Button_3.Icon = 'camera.png';
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.Button_5 = uibutton(app.Tab, 'push');
            app.Button_5.Text = '🎨';
            app.ROSLabel = uilabel(app.Tab);
            app.ROSLabel.Text = 'ROS';
            app.CheckBox_10 = uicheckbox(app.Tab);
            app.CheckBox_10.Text = '';
            app.Slider_11 = uislider(app.Tab);
            app.Slider_11.MajorTicks = [];
            app.Slider_11.MajorTickLabels = {};
            app.Slider_11.MinorTicks = [];
            app.Button_6 = uibutton(app.Tab, 'push');
            app.Button_6.Text = '🎨';
            app.HA2XLabel = uilabel(app.Tab);
            app.HA2XLabel.Text = 'HA2X';
            app.CheckBox_11 = uicheckbox(app.Tab);
            app.CheckBox_11.Text = '';
            app.Slider_12 = uislider(app.Tab);
            app.Slider_12.MajorTicks = [];
            app.Slider_12.MajorTickLabels = {};
            app.Slider_12.MinorTicks = [];
            app.Button_7 = uibutton(app.Tab, 'push');
            app.Button_7.Text = '🎨';
            app.DAPILabel_3 = uilabel(app.Tab);
            app.DAPILabel_3.Text = 'DAPI';
            app.CheckBox_12 = uicheckbox(app.Tab);
            app.CheckBox_12.Text = '';
            app.Slider_13 = uislider(app.Tab);
            app.Slider_13.MajorTicks = [];
            app.Slider_13.MajorTickLabels = {};
            app.Slider_13.MinorTicks = [];
            app.Button_8 = uibutton(app.Tab, 'push');
            app.Button_8.Text = '🎨';
            app.SELabel = uilabel(app.Tab);
            app.SELabel.Text = 'SE';
            app.Button_16 = uibutton(app.Tab, 'push');
            app.Button_16.Icon = '010-check.png';
            app.Button_16.Enable = 'off';
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.Tab, 'push');
            app.Button_17.Enable = 'off';
            app.Button_17.Text = '🏿';
            app.RowDropDownLabel = uilabel(app.Tab);
            app.RowDropDownLabel.Text = 'Row';
            app.RowDropDown = uidropdown(app.Tab);
            app.ColumnDropDownLabel = uilabel(app.Tab);
            app.ColumnDropDownLabel.Text = 'Column';
            app.ColumnDropDown = uidropdown(app.Tab);
            app.FieldDropDownLabel = uilabel(app.Tab);
            app.FieldDropDownLabel.Text = 'Field';
            app.FieldDropDown = uidropdown(app.Tab);
            app.TimepointDropDownLabel = uilabel(app.Tab);
            app.TimepointDropDownLabel.Text = 'Timepoint';
            app.TimepointDropDown = uidropdown(app.Tab);
            app.Button_18 = uibutton(app.Tab, 'push');
            app.Button_18.Enable = 'off';
            app.Button_18.Text = '📏';
            app.CheckBox_13 = uicheckbox(app.Tab);
            app.CheckBox_13.Text = '';
            app.Slider_14 = uislider(app.Tab);
            app.Slider_14.MajorTicks = [];
            app.Slider_14.MajorTickLabels = {};
            app.Slider_14.MinorTicks = [];
            app.Button_19 = uibutton(app.Tab, 'push');
            app.Button_19.Text = '🎨';
            app.CellLabel = uilabel(app.Tab);
            app.CellLabel.Text = 'Cell Label';
            app.CheckBox_15 = uicheckbox(app.Tab);
            app.CheckBox_15.Text = '';
            app.Slider_16 = uislider(app.Tab);
            app.Slider_16.MajorTicks = [];
            app.Slider_16.MajorTickLabels = {};
            app.Slider_16.MinorTicks = [];
            app.Button_21 = uibutton(app.Tab, 'push');
            app.Button_21.Text = '🎨';
            app.NucSeedLabel = uilabel(app.Tab);
            app.NucSeedLabel.Text = 'Nuc Seed';
            app.CheckBox_16 = uicheckbox(app.Tab);
            app.CheckBox_16.Text = '';
            app.Slider_17 = uislider(app.Tab);
            app.Slider_17.MajorTicks = [];
            app.Slider_17.MajorTickLabels = {};
            app.Slider_17.MinorTicks = [];
            app.Button_22 = uibutton(app.Tab, 'push');
            app.Button_22.Text = '🎨';
            app.NucLabel = uilabel(app.Tab);
            app.NucLabel.Text = 'Nuc Label';
            app.ListBox = uilistbox(app.Tab);
            app.ListBox.Items = {'Eccentricity', 'Area', 'Total Intensity', 'Mean Intensity'};
            app.ListBox.Value = 'Eccentricity';
            app.ExperimentDropDownLabel = uilabel(app.Tab);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.Tab);
            app.PlateDropDownLabel = uilabel(app.Tab);
            app.PlateDropDownLabel.Text = 'Plate';
            app.PlateDropDown = uidropdown(app.Tab);
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = 'Tab2';
            app.SegmentationLabel = uilabel(app.UIFigure);
            app.SegmentationLabel.Text = 'Segmentation';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '↓';
            app.MadewithinTorontoLabel = uilabel(app.UIFigure);
            app.MadewithinTorontoLabel.Text = 'Made with 💛 in Toronto';
            app.DanielSniderLabel = uilabel(app.UIFigure);
            app.DanielSniderLabel.Text = '@DanielSnider ツ';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '📈';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '👁';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '📁';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '➠';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '📏';
            app.Button_Segment = uibutton(app.UIFigure, 'push');
            app.Button_Segment.ButtonPushedFcn = createCallbackFcn(app, @Button_SegmentPushed, true);
            app.Button_Segment.Text = '🏿';
            app.MeasurementsLabel = uilabel(app.UIFigure);
            app.MeasurementsLabel.Text = 'Measurements';
            app.TrackingLabel = uilabel(app.UIFigure);
            app.TrackingLabel.Text = 'Tracking';
            app.PlottingLabel = uilabel(app.UIFigure);
            app.PlottingLabel.Text = 'Plotting';
            app.SpottingLabel = uilabel(app.UIFigure);
            app.SpottingLabel.Text = 'Spotting';
            app.InputDataLabel = uilabel(app.UIFigure);
            app.InputDataLabel.Text = 'Input Data';
            app.Button_Input = uibutton(app.UIFigure, 'push');
            app.Button_Input.ButtonPushedFcn = createCallbackFcn(app, @Button_InputPushed, true);
            app.Button_Input.Icon = '012-shapes.png';
            app.Button_Input.Text = '';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.Text = '🏿';
            app.Button_Spot = uibutton(app.UIFigure, 'push');
            app.Button_Spot.ButtonPushedFcn = createCallbackFcn(app, @Button_SpotPushed, true);
            app.Button_Spot.Icon = '013-eye-open.png';
            app.Button_Spot.Text = '';
            app.Button_Plot = uibutton(app.UIFigure, 'push');
            app.Button_Plot.ButtonPushedFcn = createCallbackFcn(app, @Button_PlotPushed, true);
            app.Button_Plot.Icon = '001-dot-plot.png';
            app.Button_Plot.Text = '';
            app.Button_Track = uibutton(app.UIFigure, 'push');
            app.Button_Track.ButtonPushedFcn = createCallbackFcn(app, @Button_TrackPushed, true);
            app.Button_Track.Text = '➠';
            app.Button_Measure = uibutton(app.UIFigure, 'push');
            app.Button_Measure.ButtonPushedFcn = createCallbackFcn(app, @Button_MeasurePushed, true);
            app.Button_Measure.Text = '📏';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '🎴1️⃣ ';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '⬤🆑';
            app.ColorButton = uibutton(app.UIFigure, 'push');
            app.ColorButton.Text = 'Color';
            app.Seed1NucleusPanel = uipanel(app.UIFigure);
            app.Seed1NucleusPanel.Title = 'Seed 1: Nucleus';
            app.CheckBox_4 = uicheckbox(app.Seed1NucleusPanel);
            app.CheckBox_4.Text = '';
            app.ColorLamp_4 = uilamp(app.Seed1NucleusPanel);
            app.Slider_4 = uislider(app.Seed1NucleusPanel);
            app.Slider_4.Limits = [0 50];
            app.Slider_4.MajorTickLabels = {'0', '25', '50'};
            app.Slider_4.MinorTicks = [];
            app.SingleCellAnalysisWorkflowLabel = uilabel(app.UIFigure);
            app.SingleCellAnalysisWorkflowLabel.Text = 'Single Cell Analysis Workflow';
            app.DisplaySettingsLabel = uilabel(app.UIFigure);
            app.DisplaySettingsLabel.Text = 'Display Settings';
            app.KafriLabLabel = uilabel(app.UIFigure);
            app.KafriLabLabel.Text = '@Kafri-Lab 🔬';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end